classdef MathGraphs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        TabPoly                   matlab.ui.container.Tab
        x3Label                   matlab.ui.control.Label
        PolyCEditField            matlab.ui.control.NumericEditField
        x4Label                   matlab.ui.control.Label
        PolyBEditField            matlab.ui.control.NumericEditField
        x5Label                   matlab.ui.control.Label
        PolyAEditField            matlab.ui.control.NumericEditField
        FunktionEditFieldLabel    matlab.ui.control.Label
        PolyFunktionEditField     matlab.ui.control.EditField
        x2Label                   matlab.ui.control.Label
        PolyDEditField            matlab.ui.control.NumericEditField
        x1Label                   matlab.ui.control.Label
        PolyEEditField            matlab.ui.control.NumericEditField
        x0Label                   matlab.ui.control.Label
        PolyFEditField            matlab.ui.control.NumericEditField
        PolyShowGraphButton       matlab.ui.control.Button
        PolyUIAxes                matlab.ui.control.UIAxes
        TabTrigo                  matlab.ui.container.Tab
        TrigoShowGraphButton      matlab.ui.control.Button
        TrigoDropDown             matlab.ui.control.DropDown
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        TrigoAmplitudeEditField   matlab.ui.control.NumericEditField
        TrigoWertEditField        matlab.ui.control.EditField
        TrigoUIAxes               matlab.ui.control.UIAxes
        TabFrei                   matlab.ui.container.Tab
        FreiShowGraphButton       matlab.ui.control.Button
        FreiWertEditField_2       matlab.ui.control.EditField
        FreiWertEditField_1       matlab.ui.control.EditField
        Funktion1Label            matlab.ui.control.Label
        Funktion2Label            matlab.ui.control.Label
        XSwitchLabel              matlab.ui.control.Label
        XSwitch                   matlab.ui.control.Switch
        YSwitchLabel              matlab.ui.control.Label
        YSwitch                   matlab.ui.control.Switch
        SkalaLogarythmierenLabel  matlab.ui.control.Label
        FreiUIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xline(app.PolyUIAxes, 0);
            yline(app.PolyUIAxes, 0);
            xline(app.TrigoUIAxes, 0);
            yline(app.TrigoUIAxes, 0);
            xline(app.FreiUIAxes, 0);
            yline(app.FreiUIAxes, 0);
        end

        % Button pushed function: PolyShowGraphButton
        function PolyShowGraphButtonPushed(app, event)
            %% Berechnung Graph
            coef=[
                  app.PolyAEditField.Value, ...
                  app.PolyBEditField.Value, ...
                  app.PolyCEditField.Value, ...
                  app.PolyDEditField.Value, ...
                  app.PolyEEditField.Value, ...
                  app.PolyFEditField.Value, ...
                 ]; % coefficients 
            fplot(app.PolyUIAxes, poly2sym(coef));
            xline(app.PolyUIAxes, 0);
            yline(app.PolyUIAxes, 0);
        end

        % Button pushed function: TrigoShowGraphButton
        function TrigoShowGraphButtonPushed(app, event)
            func = app.TrigoDropDown.Value;
            a = num2str(app.TrigoAmplitudeEditField.Value);
            %x = linspace(-pi,pi,100);
            value = app.TrigoWertEditField.Value;
            if func == "Sinus"
                y = strcat(a, "*sin(", value, ")");
            elseif func == "Cosinus"
                y = strcat(a, "*cos(", value, ")");
            else
                y = strcat(a, "*tan(", value, ")");
            end
            fplot(app.TrigoUIAxes, @(x) eval(y));
            xline(app.TrigoUIAxes, 0);
            yline(app.TrigoUIAxes, 0);
            
        end

        % Callback function: FreiShowGraphButton, XSwitch, YSwitch
        function FreiShowGraphButtonPushed(app, event)
            y1 = num2str(app.FreiWertEditField_1.Value);
            y2 = num2str(app.FreiWertEditField_2.Value);
            
            if app.XSwitch.Value  == "Off"
                app.FreiUIAxes.XScale = 'linear';
            else
                app.FreiUIAxes.XScale = 'log';
            end
            
            if app.YSwitch.Value  == "Off"
                app.FreiUIAxes.YScale = 'linear';
            else
                app.FreiUIAxes.YScale = 'log';
            end
            
            fplot(app.FreiUIAxes, @(x) eval(y1));
            hold(app.FreiUIAxes,'on')
            fplot(app.FreiUIAxes, @(x) eval(y2));
            hold(app.FreiUIAxes,'off')
            xline(app.FreiUIAxes, 0);
            yline(app.FreiUIAxes, 0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabPoly = uitab(app.TabGroup);
            app.TabPoly.Title = 'Polynom Funktionen';
            app.x3Label = uilabel(app.TabPoly);
            app.x3Label.Text = 'x^3';
            app.PolyCEditField = uieditfield(app.TabPoly, 'numeric');
            app.x4Label = uilabel(app.TabPoly);
            app.x4Label.Text = 'x^4';
            app.PolyBEditField = uieditfield(app.TabPoly, 'numeric');
            app.x5Label = uilabel(app.TabPoly);
            app.x5Label.Text = 'x^5';
            app.PolyAEditField = uieditfield(app.TabPoly, 'numeric');
            app.FunktionEditFieldLabel = uilabel(app.TabPoly);
            app.FunktionEditFieldLabel.Text = 'Funktion:';
            app.PolyFunktionEditField = uieditfield(app.TabPoly, 'text');
            app.PolyFunktionEditField.Editable = 'off';
            app.x2Label = uilabel(app.TabPoly);
            app.x2Label.Text = 'x^2';
            app.PolyDEditField = uieditfield(app.TabPoly, 'numeric');
            app.x1Label = uilabel(app.TabPoly);
            app.x1Label.Text = 'x^1';
            app.PolyEEditField = uieditfield(app.TabPoly, 'numeric');
            app.x0Label = uilabel(app.TabPoly);
            app.x0Label.Text = 'x^0';
            app.PolyFEditField = uieditfield(app.TabPoly, 'numeric');
            app.PolyShowGraphButton = uibutton(app.TabPoly, 'push');
            app.PolyShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @PolyShowGraphButtonPushed, true);
            app.PolyShowGraphButton.Text = 'ShowGraph';
            app.PolyUIAxes = uiaxes(app.TabPoly);
            title(app.PolyUIAxes, 'Title')
            xlabel(app.PolyUIAxes, 'X')
            ylabel(app.PolyUIAxes, 'Y')
            zlabel(app.PolyUIAxes, 'Z')
            app.TabTrigo = uitab(app.TabGroup);
            app.TabTrigo.Title = 'Trigonometrische Funktionen';
            app.TrigoShowGraphButton = uibutton(app.TabTrigo, 'push');
            app.TrigoShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @TrigoShowGraphButtonPushed, true);
            app.TrigoShowGraphButton.Text = 'ShowGraph';
            app.TrigoDropDown = uidropdown(app.TabTrigo);
            app.TrigoDropDown.Items = {'Sinus', 'Cosinus', 'Tangens'};
            app.TrigoDropDown.Value = 'Sinus';
            app.AmplitudeEditFieldLabel = uilabel(app.TabTrigo);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.TrigoAmplitudeEditField = uieditfield(app.TabTrigo, 'numeric');
            app.TrigoAmplitudeEditField.Value = 1;
            app.TrigoWertEditField = uieditfield(app.TabTrigo, 'text');
            app.TrigoWertEditField.Value = 'x';
            app.TrigoUIAxes = uiaxes(app.TabTrigo);
            title(app.TrigoUIAxes, 'Title')
            xlabel(app.TrigoUIAxes, 'X')
            ylabel(app.TrigoUIAxes, 'Y')
            zlabel(app.TrigoUIAxes, 'Z')
            app.TabFrei = uitab(app.TabGroup);
            app.TabFrei.Title = 'Weitere Funktionen';
            app.FreiShowGraphButton = uibutton(app.TabFrei, 'push');
            app.FreiShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @FreiShowGraphButtonPushed, true);
            app.FreiShowGraphButton.Text = 'ShowGraph';
            app.FreiWertEditField_2 = uieditfield(app.TabFrei, 'text');
            app.FreiWertEditField_1 = uieditfield(app.TabFrei, 'text');
            app.Funktion1Label = uilabel(app.TabFrei);
            app.Funktion1Label.Text = 'Funktion 1:';
            app.Funktion2Label = uilabel(app.TabFrei);
            app.Funktion2Label.Text = 'Funktion 2:';
            app.XSwitchLabel = uilabel(app.TabFrei);
            app.XSwitchLabel.Text = 'X';
            app.XSwitch = uiswitch(app.TabFrei, 'slider');
            app.XSwitch.ValueChangedFcn = createCallbackFcn(app, @FreiShowGraphButtonPushed, true);
            app.YSwitchLabel = uilabel(app.TabFrei);
            app.YSwitchLabel.Text = 'Y';
            app.YSwitch = uiswitch(app.TabFrei, 'slider');
            app.YSwitch.ValueChangedFcn = createCallbackFcn(app, @FreiShowGraphButtonPushed, true);
            app.SkalaLogarythmierenLabel = uilabel(app.TabFrei);
            app.SkalaLogarythmierenLabel.Text = 'Skala Logarythmieren';
            app.FreiUIAxes = uiaxes(app.TabFrei);
            title(app.FreiUIAxes, 'Title')
            xlabel(app.FreiUIAxes, 'X')
            ylabel(app.FreiUIAxes, 'Y')
            zlabel(app.FreiUIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MathGraphs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end